classdef characterizationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ConfidenceSlider              matlab.ui.control.Slider
        ConfidenceSliderLabel         matlab.ui.control.Label
        SelectthedesiredmarkersLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        BlackSpinner                  matlab.ui.control.Spinner
        BlackSpinnerLabel             matlab.ui.control.Label
        YellowSpinner                 matlab.ui.control.Spinner
        YellowSpinnerLabel            matlab.ui.control.Label
        MagentaSpinner                matlab.ui.control.Spinner
        MagentaSpinnerLabel           matlab.ui.control.Label
        CyanSpinner                   matlab.ui.control.Spinner
        CyanSpinnerLabel              matlab.ui.control.Label
        BlueSpinner                   matlab.ui.control.Spinner
        BlueSpinnerLabel              matlab.ui.control.Label
        GreenSpinner                  matlab.ui.control.Spinner
        GreenSpinnerLabel             matlab.ui.control.Label
        RedSpinner                    matlab.ui.control.Spinner
        RedSpinnerLabel               matlab.ui.control.Label
        StartButton                   matlab.ui.control.StateButton
        RefreshButton                 matlab.ui.control.Button
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        ResolutionDropDown            matlab.ui.control.DropDown
        ResolutionDropDownLabel       matlab.ui.control.Label
        CameraDropDown                matlab.ui.control.DropDown
        CameraDropDownLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % CharacterizationGUI: Real-time marker detection and plotting at up to 60 fps
    %
    % This class implements an App Designer application that:
    %   - Selects and configures a webcam
    %   - Detects colored markers in live video frames
    %   - Records marker positions over time in timetables
    %   - Displays marker trajectories in a UIAxes component
    %   - Saves non-empty markers to timestamped .mat files

    properties (Access = private)
        adqManager     % Handles marker acquisition and detection
        processing     % Boolean flag for main processing loop
        markers        % Container storing marker objects
        markersN       % Total number of marker instances
        m              % Image height in pixels
        n              % Image width in pixels
    end

    methods (Access = private)
        function refreshCameraList(app)
            % refreshCameraList - Populate the camera dropdown
            %   Queries available webcams and updates CameraDropDown items.
            cams = webcamlist;
            app.CameraDropDown.Items = cams;
            app.loadModels();
        end

        function stopProcess(app)
            % stopProcess - Terminate the processing loop
            %   Sets the processing flag to false to exit the main loop.
            app.processing = false;
        end

        function updateResolutions(app, selectedCam)
            % updateResolutions - Populate resolution options
            %   selectedCam: Name of the current webcam
            %   Retrieves AvailableResolutions and updates ResolutionDropDown.
            resolutions = webcam(selectedCam).AvailableResolutions;
            app.ResolutionDropDown.Items = resolutions;
            defaultRes = '432x240';
            if any(strcmp(resolutions, defaultRes))
                app.ResolutionDropDown.Value = defaultRes;
            else
                app.ResolutionDropDown.Value = resolutions{1};
            end
        end

        function loadModels(app)
                           % 1) Localiza el directorio donde está este .mlapp
                codeDir = fileparts( mfilename('fullpath') );
                % 2) Construye la ruta al subfolder 'models'
                modelDir = fullfile( codeDir, 'models' );
                % 3) Busca .mat en esa carpeta
                modelFiles = dir( fullfile(modelDir, '*.mat') );
                if isempty(modelFiles)
                    warning('No se encontraron archivos .mat en %s', modelDir);
                end
                % 4) Rellena el dropdown con los nombres
                app.ModelDropDown.Items = { modelFiles.name };
        end

        function saving(app)
            % saving - Save all markers with data to a .mat file
            %   Filters out markers without data, resamples each to 30 Hz,
            %   and saves them with a timestamped filename.
            allMarkers = app.markers.markers;
            validIdx = ~cellfun(@(m) isempty(m.data), allMarkers);
            validMarkers = allMarkers(validIdx);
            for k = 1:numel(validMarkers)
                T = validMarkers{k}.data;
                maxTime = max(T.Time);
                newTimes = seconds(0 : 1/30 : seconds(maxTime))';
                validMarkers{k}.data = retime(T, newTimes, 'linear');
            end
            timestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            fileName = sprintf('Markers_%s.mat', char(timestamp));
            save(fileName, 'validMarkers', 'timestamp');
            app.Label.Text = sprintf('Saved %d markers', numel(validMarkers));
        end

        function process(app)
            % process - Main loop for frame acquisition and plotting
            %   Captures frames, detects markers, overlays shapes,
            %   and updates trajectory plot every 30 frames (~60 fps).
            frameCounter = 0;
            [I, ~, ~, ~] = app.adqManager.detectMarkers();
            [app.m, app.n, ~] = size(I);
            while app.processing
                [I, ~, bboxes, labels] = app.adqManager.detectMarkers();
                if ~isempty(I) && ~isempty(bboxes)
                    app.markers = app.markers.updateMarkers(bboxes, labels);
                    % Overlay detected marker circles
                    for idx = 1:app.markersN
                        dataTbl = app.markers.markers{idx}.data;
                        if ~isempty(dataTbl)
                            pos = dataTbl(end, {'x','y'});
                            radius = app.markers.markers{idx}.radii / 2;
                            I = insertShape(I, 'FilledCircle', [pos.x, pos.y, radius], 'LineWidth', 1);
                        end
                    end
                    app.Image.ImageSource = I;
                    % Update trajectory plot periodically
                    if mod(frameCounter, 30) == 0
                        cla(app.UIAxes);
                        hold(app.UIAxes, 'on');
                        markersData = app.markers.markers(~cellfun(@(m) isempty(m.data), app.markers.markers));
                        for idx = 1:numel(markersData)
                            T = markersData{idx}.data;
                            plot(app.UIAxes, T.Time, T.x, '-', 'LineWidth', 1.5, 'DisplayName', markersData{idx}.colorName);
                            plot(app.UIAxes, T.Time, T.y, '-', 'LineWidth', 1.5);
                        end
                        hold(app.UIAxes, 'off');
                        legend(app.UIAxes, 'show');
                        xlabel(app.UIAxes, 'Time (s)');
                        ylabel(app.UIAxes, 'Coordinates');
                        title(app.UIAxes, 'Marker Trajectories');
                        frameCounter = 0;
                    end
                    drawnow limitrate;
                    frameCounter = frameCounter + 1;
                end
            end
            clear cam;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % startupFcn – Initializes the application on launch.
            %
            % This function runs when the app starts and performs the following steps:
            %   1) refreshCameraList(app): Populate the CameraDropDown with available webcams.
            %   2) app.UIFigure.Name = 'Characterizer': Set the main window’s title.
            %   3) updateResolutions(app, app.CameraDropDown.Value): Fill the ResolutionDropDown based on the selected camera.
            %   4) loadModels(app): Load available model filenames into the ModelDropDown.
            %
            % No inputs are required; it relies solely on the app’s properties.
            refreshCameraList(app);
            app.UIFigure.Name = 'Characterizer';
            updateResolutions(app, app.CameraDropDown.Value);
            loadModels(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshCameraList(app)
            updateResolutions(app,app.CameraDropDown.Value)


        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            refreshCameraList(app)
            updateResolutions(app,app.CameraDropDown.Value)

        end

        % Drop down opening function: ModelDropDown
        function ModelDropDownOpening(app, event)

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)

            % StartButtonValueChanged - Toggle data acquisition when the Start button is clicked
            %   On button press (Value == true):
            %     * Change StartButton text to 'Stop'
            %     * Display 'Generating acquisition model'
            %     * Instantiate adqManager with selected webcam and model
            %     * Apply chosen resolution and confidence threshold
            %     * Display 'Adquiring data'
            %     * Read color spinner values and initialize markers
            %     * Enable processing and call process()
            %   On button release (Value == false):
            %     * Change StartButton text to 'Start'
            %     * Display 'Data acquisition stopped'
            %     * Stop processing and save collected markers
            if app.StartButton.Value
                app.StartButton.Text = 'Stop';
                app.Label.Text = 'Generating acquisition model';
                app.adqManager = acquisition('webcam', app.CameraDropDown.Value, 'none', 'DL', app.ModelDropDown.Value);
                app.adqManager.camera.Resolution = app.ResolutionDropDown.Value;
                app.adqManager.confidence = app.ConfidenceSlider.Value;
                app.Label.Text = 'Adquiring data';
                r = app.RedSpinner.Value;
                g = app.GreenSpinner.Value;
                b = app.BlueSpinner.Value;
                c = app.CyanSpinner.Value;
                mag = app.MagentaSpinner.Value;
                y = app.YellowSpinner.Value;
                k = app.BlackSpinner.Value;
                colorCount = [r, g, b, c, mag, y, k, 0];
                app.markersN = sum(colorCount);
                app.markers = markersInterface(colorCount, 3, 3);
                app.processing = true;
                app.process();
            else
                app.StartButton.Text = 'Start';
                app.Label.Text = 'Data acquisition stopped';
                app.stopProcess();
                app.saving();

            end

        end

        % Value changed function: ConfidenceSlider
        function ConfidenceSliderValueChanged(app, event)
            value = app.ConfidenceSlider.Value;
            app.adqManager.confidence = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {534, 534};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {369, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Options';
            app.CameraDropDownLabel = uilabel(app.LeftPanel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.LeftPanel);
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.ResolutionDropDownLabel = uilabel(app.LeftPanel);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.LeftPanel);
            app.ResolutionDropDown.Tag = 'resolution';
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelDropDownOpening, true);
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Tag = 'StartStop';
            app.StartButton.Text = 'Start';
            app.Panel = uipanel(app.LeftPanel);
            app.RedSpinnerLabel = uilabel(app.Panel);
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.Panel);
            app.RedSpinner.Tag = 'red';
            app.GreenSpinnerLabel = uilabel(app.Panel);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.Panel);
            app.GreenSpinner.Tag = 'green';
            app.BlueSpinnerLabel = uilabel(app.Panel);
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.Panel);
            app.BlueSpinner.Tag = 'blue';
            app.CyanSpinnerLabel = uilabel(app.Panel);
            app.CyanSpinnerLabel.Text = 'Cyan';
            app.CyanSpinner = uispinner(app.Panel);
            app.CyanSpinner.Tag = 'cyan';
            app.MagentaSpinnerLabel = uilabel(app.Panel);
            app.MagentaSpinnerLabel.Text = 'Magenta';
            app.MagentaSpinner = uispinner(app.Panel);
            app.MagentaSpinner.Tag = 'magenta';
            app.YellowSpinnerLabel = uilabel(app.Panel);
            app.YellowSpinnerLabel.Text = 'Yellow';
            app.YellowSpinner = uispinner(app.Panel);
            app.YellowSpinner.Tag = 'yellow';
            app.BlackSpinnerLabel = uilabel(app.Panel);
            app.BlackSpinnerLabel.Text = 'Black';
            app.BlackSpinner = uispinner(app.Panel);
            app.BlackSpinner.Tag = 'black';
            app.SelectthedesiredmarkersLabel = uilabel(app.LeftPanel);
            app.SelectthedesiredmarkersLabel.Text = 'Select the desired markers';
            app.ConfidenceSliderLabel = uilabel(app.LeftPanel);
            app.ConfidenceSliderLabel.Text = 'Confidence';
            app.ConfidenceSlider = uislider(app.LeftPanel);
            app.ConfidenceSlider.Limits = [0 1];
            app.ConfidenceSlider.ValueChangedFcn = createCallbackFcn(app, @ConfidenceSliderValueChanged, true);
            app.ConfidenceSlider.Value = 0.3;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'plotting';
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = characterizationGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end